classdef Q_daq2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SampleinformationMenu           matlab.ui.container.Menu
        SetdirectoryMenu                matlab.ui.container.Menu
        ConfigurationfileMenu           matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        SaveQplotMenu                   matlab.ui.container.Menu
        SaveOscillationplotMenu         matlab.ui.container.Menu
        SaveHysteresisplotMenu          matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        EnableallMenu                   matlab.ui.container.Menu
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UserdialogTextAreaLabel         matlab.ui.control.Label
        Userdialog                      matlab.ui.control.TextArea
        SingletestPanel                 matlab.ui.container.Panel
        RunButton                       matlab.ui.control.Button
        F_oscEditFieldLabel             matlab.ui.control.Label
        F_osc_sng                       matlab.ui.control.NumericEditField
        VppEditFieldLabel               matlab.ui.control.Label
        Vpp_sng                         matlab.ui.control.NumericEditField
        NcycEditFieldLabel              matlab.ui.control.Label
        Ncyc_sng                        matlab.ui.control.NumericEditField
        OSEditFieldLabel                matlab.ui.control.Label
        OS_sng                          matlab.ui.control.NumericEditField
        Lamp_single                     matlab.ui.control.Lamp
        SweepcontrolPanel               matlab.ui.container.Panel
        Lamp_sweep                      matlab.ui.control.Lamp
        StartSweep                      matlab.ui.control.Button
        F_minEditField_2Label           matlab.ui.control.Label
        F_min                           matlab.ui.control.NumericEditField
        F_maxEditFieldLabel             matlab.ui.control.Label
        F_max                           matlab.ui.control.NumericEditField
        StepsEditFieldLabel             matlab.ui.control.Label
        Steps                           matlab.ui.control.NumericEditField
        CyclesEditFieldLabel            matlab.ui.control.Label
        Cycles                          matlab.ui.control.NumericEditField
        OSEditFieldLabel_2              matlab.ui.control.Label
        OS                              matlab.ui.control.NumericEditField
        RepLabel                        matlab.ui.control.Label
        Repeats                         matlab.ui.control.NumericEditField
        Stopsweep                       matlab.ui.control.StateButton
        VppEditFieldLabel_2             matlab.ui.control.Label
        Vpp                             matlab.ui.control.NumericEditField
        Centremotor                     matlab.ui.control.CheckBox
        HardwarecommsPanel              matlab.ui.container.Panel
        HS4conn                         matlab.ui.control.Button
        FGconn                          matlab.ui.control.Button
        Lamp_HS4                        matlab.ui.control.Lamp
        Lamp_FG                         matlab.ui.control.Lamp
        Motorconn                       matlab.ui.control.Button
        Lamp_motor                      matlab.ui.control.Lamp
        FGon                            matlab.ui.control.StateButton
        FGonLamp                        matlab.ui.control.Lamp
        SweepprogreesLabel              matlab.ui.control.Label
        progressbar                     matlab.ui.control.LinearGauge
        UIAxes3                         matlab.ui.control.UIAxes
        PiezomotorcontrolPanel          matlab.ui.container.Panel
        Forward                         matlab.ui.control.Button
        Stop_motor                      matlab.ui.control.Button
        Backwards                       matlab.ui.control.Button
        MotorselectorSwitch             matlab.ui.control.ToggleSwitch
        StepsEditFieldLabel_2           matlab.ui.control.Label
        Piezo_steps                     matlab.ui.control.NumericEditField
        Lamp_centre                     matlab.ui.control.Lamp
        ToleranceVEditFieldLabel        matlab.ui.control.Label
        Tolerance                       matlab.ui.control.NumericEditField
        GainFLabel                      matlab.ui.control.Label
        GainF                           matlab.ui.control.NumericEditField
        GainSEditFieldLabel             matlab.ui.control.Label
        GainS                           matlab.ui.control.NumericEditField
        SpeedEditFieldLabel             matlab.ui.control.Label
        Speed                           matlab.ui.control.NumericEditField
        Autocentre                      matlab.ui.control.Button
        StopC                           matlab.ui.control.StateButton
        CurrentdirectoryEditFieldLabel  matlab.ui.control.Label
        Currentdirectory                matlab.ui.control.EditField
        SensorcheckButton               matlab.ui.control.Button
        Closecheck                      matlab.ui.control.StateButton
        CycleprogressGaugeLabel         matlab.ui.control.Label
        Cycleprogress                   matlab.ui.control.LinearGauge
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        cal
        chans
        counter
        data
        devices
        dir
        F
        fgen
        LibTiePie % Description
        LibBuilt % Description
        nscp
        phase
        piezoDriver
        scp
        SN
        sn_c
        sampleinfo % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cal.F_N = 321.21*1.494;
            app.cal.S_m = 2.5e-6*1.494;
            headers = {'F [Hz]','Q^-1 []','E [GPa]'};
            app.UITable.ColumnName=headers;
            app.devices = seriallist;
            if ismac
                disp('LibTiePie is not compatible with MacOS, aborting program. Bye.')
                clear;close all
                return
            else
                cdir = pwd();
                eval(['addpath ' cdir])
                if app.LibBuilt == 1
                    return
                else
                    h = msgbox('Building LibTiePie');
                    app.LibTiePie = LibTiePie.Library;
                    i = msgbox(['Status of library initialisation: ' app.LibTiePie.LastStatusStr ', click OK to continue']);
                    if ishandle(h)
                        close(h)
                    end
                    while ishandle(i)
                        pause(0.01)
                    end
                end
                app.LibBuilt = 1;
                app.counter = 0;
                headers = {'F_{osc} [Hz]','Q^{-1} []','E [GPa]'};
                app.UITable(1:10,headers);
            end
        end

        % Button pushed function: HS4conn
        function HS4connPushed(app, event)
            % This callback opens a connection to the handyscope following compilation of the TiePie c library
            app.Lamp_HS4.Enable='off';
            if ismac
                disp('LibTiePie is not compatible with MacOS, aborting program. Bye.')
                clear;close all
                return
            else
                cdir = pwd();
                eval(['addpath ' cdir])
                if app.LibBuilt == 1
                else
                    h = msgbox('Building LibTiePie');
                    app.LibTiePie = LibTiePie.Library;
                    i = msgbox(['Status of library initialisation: ' app.LibTiePie.LastStatusStr ', click OK to continue']);
                    if ishandle(h)
                        close(h)
                    end
                    while ishandle(i)
                        pause(0.01)
                    end
                end
                app.LibBuilt = 1;
                app.counter = 0;
                import LibTiePie.Const.*;
                import LibTiePie.Enum.*;
                sel = questdlg('Search for Handyscopes?','Connecting to instruments','OK','Cancel','Cancel');
                if strcmp(sel,'Cancel')
                    return
                else
                    [app.scp,app.SN,app.sn_c,app.nscp,msg] = get_scp(app.LibTiePie);
                    if app.scp == -1
                        app.Userdialog.Value = 'No oscilloscopes found, if you are experiencing difficulties try turning it on and off again...';
                    else
                        app.chans = length(app.scp.Channels);
                        app.Lamp_HS4.Enable='on';
                        app.Userdialog.Value = msg;
                        if strcmp(app.Lamp_FG.Enable,'on') && strcmp(app.Lamp_motor.Enable, 'on')
                            app.RunButton.Enable='on';
                            app.StartSweep.Enable='on';
                            app.Forward.Enable = 'on';
                            app.Backwards.Enable = 'on';
                            app.Stop_motor.Enable = 'on';
                            app.MotorselectorSwitch.Enable = 'on';
                            app.Autocentre.Enable = 'on';
                            app.StopC.Enable = 'on';
                        end
                    end
                end
            end
        end

        % Button pushed function: FGconn
        function FGconnPushed(app, event)
            app.devices
            % Callback to open a connection to the function generator, model: Agilent 33210A
            app.Lamp_FG.Enable='off';
            app.fgen=InitVISA('USB0::0x0957::0x1507::MY48006424::0::INSTR');
            app.Userdialog.Value = 'Connected to function generator successfully';
            app.Lamp_FG.Enable='on';
            if strcmp(app.Lamp_HS4.Enable,'on') && strcmp(app.Lamp_motor.Enable, 'on')
                app.RunButton.Enable='on';
                app.StartSweep.Enable='on';
                app.Forward.Enable = 'on';
                app.Backwards.Enable = 'on';
                app.Stop_motor.Enable = 'on';
                app.MotorselectorSwitch.Enable = 'on';
                app.Autocentre.Enable = 'on';
                app.StopC.Enable = 'on';
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %Run a single acquisition of the module with a bursted mode of data acquisition starting at a phase of 270.
            app.Lamp_single.Enable='off';
            app.RunButton.Enable='off';
            app.StartSweep.Enable='off';
            app.HS4conn.Enable='off';
            app.FGconn.Enable='off';
            app.Motorconn.Enable='off';
            app.FGconn.Enable='off';
            app.Forward.Enable='off';
            app.MotorselectorSwitch.Enable='off';
            app.Autocentre.Enable='off';
            app.StopC.Enable='off';
            app.SensorcheckButton.Enable='off';
            app.Closecheck.Enable='off';
            import LibTiePie.Const.*;
            import LibTiePie.Enum.*;
            app.LibTiePie.DeviceList.update();
            if app.scp.IsRunning==1
                app.scp.stop();
            end
            dts = [1/5e5 1/((5+1/4.8)*1e5) 1/1.953125e5];
            app.scp(1).MeasureMode = MM.STREAM;
            app.scp(1).Resolution = 16;
            dt_min = dts(3);
            dt_req = 1/(app.F_osc_sng.Value*app.OS_sng.Value);
            mult = round(dt_req/dt_min);
            dt_acq = mult*dt_min;
            app.scp(1).SampleFrequency = 1/dt_acq;
            app.scp(1).RecordLength = app.Ncyc_sng.Value*app.OS_sng.Value+4*app.OS_sng.Value;
            F_osc_acq = 1/(dt_acq*app.OS_sng.Value);
            rng = [0.2 0.4 0.8 2 4 8];
            rng_t = (2*app.Vpp_sng.Value)-rng;
            rng_t(rng_t>0)=-inf;
            [~,I] = max(rng_t);
            rng_t = rng(I);
            for ch = 1:2
                app.scp.Channels(ch).Enabled = true; %Set whether the channel records
                app.scp.Channels(ch).Range = rng_t;
                app.scp.Channels(ch).Coupling = CK.DCV;
            end
            for ch = 3:4
                app.scp.Channels(ch).Enabled = false; %Set whether the channel records
            end
            fopen(app.fgen);
            fprintf(app.fgen, 'OUTP:SYNC ON');
            fprintf(app.fgen, 'OUTP:LOAD 50');
            fprintf(app.fgen, ['APPL:SIN ' num2str(F_osc_acq) ',' num2str(app.Vpp_sng.Value)]);
            fprintf(app.fgen, ['VOLT:OFFS ' num2str(app.Vpp_sng.Value/2)]);
            fprintf(app.fgen, 'BURS:MODE TRIG');
            fprintf(app.fgen, ['NCYC ' num2str(app.Ncyc_sng.Value)]);
            fprintf(app.fgen, 'BURS:PHAS 270');
            app.Lamp_single.Enable='on';
            app.Userdialog.Value = 'Single frequency acquisition running!';
            pause(0.1)
            app.scp.start();
            pause(app.OS_sng*2*dt_acq)
            fprintf(app.fgen, 'OUTP ON');
            fclose(app.fgen);
            tic
            while ~app.scp.IsDataReady
                app.Cycleprogress.Value = toc/((app.Ncyc_sng.Value*app.OS_sng.Value+4*app.OS_sng.Value)*dt_acq);
                pause(1e-3); % 1 ms delay, to save CPU time
            end
            app.scp.stop();
            app.Lamp_single.Enable='off';
            fopen(app.fgen);
            fprintf(app.fgen, 'OUTP OFF');
            fclose(app.fgen);
            app.counter = app.counter+1;
            app.data(app.counter).raw = app.scp.getData();
            raw = app.data(app.counter).raw;
            fs = app.scp.SampleFrequency;
            app.data(app.counter).fs = app.scp.SampleFrequency;
            app.data(app.counter).f_Hz = F_osc_acq;
            nsmp=length(raw(:,1));
            time = [0:1:(length(raw(:,1))-1)]*(1/app.data(app.counter).fs);
            f_dig = single(raw(:,1))'-mean(single(raw(:,1)));
            s_dig = single(raw(:,2))'-mean(single(raw(:,2)));
            plot(app.UIAxes,time,f_dig,time,s_dig);
            plot(app.UIAxes3,s_dig,f_dig);
            app.Userdialog.Value = 'Acquistion terminated!';
            app.Userdialog.Value = 'Calculating Q value!';
            %% fit the data- Gaussian elimination has some difficulty for some reason...
            %             F1      =   sin(2*pi*F_osc_acq*time);
            %             F2      =   cos(2*pi*F_osc_acq.*time);
            %             F3      =   time;
            %             F4      =   ones(size(time));
            %             F       =   [F1 F2];
            %             disp(num2str(size(F)))
            %             app.RunButton.Enable='on';
            %             app.StartSweep.Enable='on';
            %             app.HS4conn.Enable='on';
            %             app.FGconn.Enable='on';
            %             fitCF       =   F\f_dig;
            %             plot(fitCF)
            %             hold on
            %             AmplF  =   norm(fitCF);
            %             ForceP  =   atan2(fitCF(2),fitCF(1));
            %             disp(num2str(ForceP))
            %             fitCS       =   F\s_dig;
            %             plot(fitCS)
            %             AmplS  =   abs(norm(fitCS));
            %             ShortP  =   atan2(fitCS(2),fitCS(1));
            %             disp(num2str(ShortP))
            Force_a=(f_dig-min(f_dig))/(max(f_dig)-min(f_dig)).*2;
            Short_a=(s_dig-min(s_dig))/(max(s_dig)-min(s_dig)).*2;
            NFFT = 2^nextpow2(nsmp);
            Yf = fft(Force_a-1,NFFT)/nsmp;
            Ys = fft(Short_a-1,NFFT)/nsmp;
            f = fs/2*linspace(0,1,NFFT/2+1);
            pfftF=angle(Yf);
            pfftS=angle(Ys);
            pos=find(f>F_osc_acq);
            phase_rad=pfftF(pos(1))-pfftS(pos(1));
            app.data(app.counter).phase = phase_rad;
            %phase_rad=   real(atan(tan(ForceP-ShortP)));
            scatter(app.UIAxes2,app.data(app.counter).f_Hz,phase_rad);
            set(app.UIAxes2,'xscal','log')
            set(app.UIAxes2,'ylim',[-0.01 0.01])
            hold(app.UIAxes2,'on');
            app.Userdialog.Value = ['Q^-1 value = ' num2str(phase_rad)];
            % Button enable/disable
            app.RunButton.Enable='on';
            app.StartSweep.Enable='on';
            app.HS4conn.Enable='on';
            app.FGconn.Enable='on';
            app.Motorconn.Enable='on';
            app.RunButton.Enable='on';
            app.StartSweep.Enable='on';
            app.HS4conn.Enable='on';
            app.FGconn.Enable='on';
            app.Motorconn.Enable='on';
            app.Forward.Enable='on';
            app.MotorselectorSwitch.Enable='on';
            app.Autocentre.Enable='on';
            app.StopC.Enable='on';
            app.SensorcheckButton.Enable='on';
            app.Closecheck.Enable='on';
        end

        % Button pushed function: StartSweep
        function StartSweepButtonPushed(app, event)
            %Code to run a frequency sweep
            app.UIAxes2.cla;
            %Check whether a directory has been setup or sample information entered
            if xor(~isfield(app.dir,'set'),~isfield(app.sampleinfo,'set'))
                if ~isfield(app.dir,'set')
                    h = msgbox('Please set a directory to save the data!','Do you want free sex?','error');
                    while ishandle(h)
                        pause(0.1)
                    end
                    SetdirectoryMenuSelected(app, event);
                elseif ~isfield(app.sampleinfo,'set')
                    h=msgbox('Please enter the sample information!','I''ve got a lovely bunch of coconuts!','error');
                    while ishandle(h)
                        pause(0.1)
                    end
                    SampleinformationMenuSelected(app, event);
                end
            elseif ~isfield(app.dir,'set') && ~isfield(app.sampleinfo,'set')
                h=msgbox('Please set a directory to save the data!','Do you want free sex?','error');
                while ishandle(h)
                    pause(0.1)
                end
                SetdirectoryMenuSelected(app, event);
                h=msgbox('Please enter the sample information!','I''ve got a lovely bunch of coconuts!','error');
                while ishandle(h)
                    pause(0.1)
                end
                SampleinformationMenuSelected(app, event);
            elseif isfield(app.sampleinfo,'set')
                if app.sampleinfo.set == 0
                    h=msgbox('Please enter the sample information!','I''ve got a lovely bunch of coconuts!','error');
                    while ishandle(h)
                        pause(0.1)
                    end
                    SampleinformationMenuSelected(app, event);
                end
            end
                app.UITable.Data=[];
                app.data=[];
                date = datestr(now, 'yyyymmddHHMM');
                mkdir(date);
                app.dir.child = [app.dir.parent '/' date];
                cd(app.dir.child);
                app.Currentdirectory.Value = app.dir.child;
                writetable(struct2table(app.sampleinfo'), [date '_sampleinfo.txt']);
                fmin_Hz = app.F_min.Value;
                fmax_Hz = app.F_max.Value;
                steps = app.Steps.Value;
                F_Hz = logspace(log10(fmin_Hz),log10(fmax_Hz),steps);
                F_Hz = sort(F_Hz,'descend');
                app.Lamp_single.Enable='off';
                app.Lamp_sweep.Enable='on';
                app.RunButton.Enable='off';
                app.StartSweep.Enable='off';
                app.Stopsweep.Enable='on';
                app.HS4conn.Enable='off';
                app.FGconn.Enable='off';
                app.Motorconn.Enable='off';
                app.Forward.Enable='off';
                app.MotorselectorSwitch.Enable='off';
                app.Autocentre.Enable='off';
                app.StopC.Enable='off';
                app.SensorcheckButton.Enable='off';
                app.Closecheck.Enable='off';
                import LibTiePie.Const.*;
                import LibTiePie.Enum.*;
                app.LibTiePie.DeviceList.update();
                overs = app.OS.Value;
                ncyc = app.Cycles.Value;
                dts = [500e3 1/((5+1/4.8)*1e5) 1/1.953125e5];
                app.scp(1).MeasureMode = MM.STREAM;
                app.scp(1).Resolution = 16;
                dt_min = dts(3);
                for j = 1:app.Repeats.Value
                    if app.Centremotor.Value == 1
                        AutocentreValueChanged(app, event)
                    end
                    rng = [0.2 0.4 0.8 2 4 8 20 40 80];
                    rng_t = (2*app.rngS)-rng;
                    rng_t(rng_t>0)=-inf;
                    [~,I] = max(rng_t);
                    rng_t = rng(I);
                    for ch = 1:2
                        app.scp.Channels(ch).Enabled = true; %Set whether the channel records
                        app.scp.Channels(ch).Range = 4;
                        app.scp.Channels(ch).Coupling = CK.DCV;
                    end
                    if app.scp.IsRunning==1
                        app.scp.stop();
                    end
                    
                    for ch = 3:4
                        app.scp.Channels(ch).Enabled = false; %Set whether the channel records
                    end
                    for i = 1:steps
                        progress = ((j-1)*steps)+i;
                        app.progressbar.Value = (progress/(steps*app.Repeats.Value))*100;
                        dt_req = 1/(F_Hz(i)*overs);
                        mult = round(dt_req/dt_min);
                        dt_acq = mult*dt_min;
                        app.scp(1).SampleFrequency = 1/dt_acq;
                        app.scp(1).RecordLength = ncyc*overs+4*ncyc;
                        t_acq = (ncyc*overs+4*ncyc)*dt_acq;
                        F_osc_acq = 1/(dt_acq*overs);
                        fopen(app.fgen);
                        fprintf(app.fgen, 'OUTP:LOAD 50');
                        fprintf(app.fgen, 'DISP:TEXT What did you want to see here?');
                        fprintf(app.fgen, ['APPL:SIN ' num2str(F_osc_acq) ',' num2str(app.Vpp.Value/2)]);
                        fprintf(app.fgen, ['VOLT:OFFS ' num2str(app.Vpp.Value/4)]);
                        pause(0.1)
                        app.Lamp_sweep.Enable='on';
                        app.Userdialog.Value = ['Sweeping frequency, currently at: ' num2str(F_osc_acq) 'Hz'];
                        pause(0.1)
                        app.scp.start();
                        pause(2*ncyc*dt_acq);
                        printf(app.fgen, 'OUTP ON');
                        fclose(app.fgen);
                        while ~app.scp.IsDataReady
                            app.Cycleprogress.Value = (toc/t_acq)*100;
                            pause(1e-3); % 1 ms delay, to save CPU time
                            
                        end
                        app.scp.stop();
                        app.Lamp_sweep.Enable='off';
                        fopen(app.fgen);
                        fprintf(app.fgen, 'OUTP OFF');
                        fclose(app.fgen);
                        pause(0.1)
                        raw = app.scp.getData();
                        fs = app.scp.SampleFrequency;
                        app.data(i,j).fs = app.scp.SampleFrequency;
                        app.data(i,j).f_Hz = F_osc_acq;
                        nsmp=length(raw(:,1));
                        time = [0:1:(length(raw(:,1))-1)]*(1/app.data(i,j).fs);
                        meanF=mean(raw(:,1));
                        meanS=mean(raw(:,2));
                        rngS=max([abs(max(raw(:,2))) abs(min(raw(:,2))) abs(max(raw(:,1))) abs(min(raw(:,1))) ]);
                        f_dig = single(raw(:,1))'-meanF;
                        s_dig = single(raw(:,2))'-meanS;
                        plot(app.UIAxes,time,f_dig,time,s_dig);
                        plot(app.UIAxes3,s_dig,f_dig);
                        app.Userdialog.Value = 'Calculating Q value!';
                        pause(0.1)
                        Force_a=(f_dig-min(f_dig))/(max(f_dig)-min(f_dig)).*2;
                        Short_a=(s_dig-min(s_dig))/(max(s_dig)-min(s_dig)).*2;
                        E_GPa = Force_a/Short_a;
                        NFFT = 2^nextpow2(nsmp);
                        Yf = fft(Force_a-1,NFFT)/nsmp;
                        Ys = fft(Short_a-1,NFFT)/nsmp;
                        f = fs/2*linspace(0,1,NFFT/2+1);
                        pfftF=angle(Yf);
                        pfftS=angle(Ys);
                        pos=find(f>F_osc_acq);
                        phase_rad=pfftF(pos(1))-pfftS(pos(1));
                        app.data(i,j).phase = phase_rad;
                        app.phase(i+(j-1)*steps) = phase_rad;
                        app.F(i+(j-1)*steps) = F_osc_acq;
                        scatter(app.UIAxes2,app.data(i,j).f_Hz,phase_rad);
                        scatter(app.UIAxes2,app.data(i,j).f_Hz,E_GPa);
                        set(app.UIAxes2,'xscal','log')
                        set(app.UIAxes2,'ylim',[-0.03 0.15])
                        set(app.UIAxes2,'xlim',[0.05 200])
                        hold(app.UIAxes2,'on');
                        app.Userdialog.Value = ['Q^-1 value = ' num2str(phase_rad)];
                        date = datestr(now,'yymmdd');
                        time = datestr(now,'HHMMss');
                        eval(['save ' num2str(date) '_' num2str(time) '_Qdaq f_dig s_dig time fs F_osc_acq phase_rad']);
                        pause(0.1)
                        app.data.uitable = [app.data.uitable; [F_osc_acq phase_rad E_GPa]];
                        app.UITable.Data = app.data.uitable;
                        if abs(meanF) > app.Tolerance.Value || abs(meanS) > app.Tolerance.Value
                            if app.Centremotor.Value == 1
                                AutocentreValueChanged(app, event)
                            end
                        end
                        if app.Stopsweep.Value == 1
                            break
                        end
                    end
                    if app.Stopsweep.Value == 1
                        break
                    end
                    
                end
                date = datestr(now,'yymmdd');
                time = datestr(now,'HHMMss');
                var = app.data;
                save([num2str(date) '_' num2str(time) '_sweep.mat'], 'var');
                % Button enable/disable
                app.Stopsweep.Value = 0;
                app.Stopsweep.Enable = 'off';
                app.RunButton.Enable='on';
                app.StartSweep.Enable='on';
                app.HS4conn.Enable='on';
                app.FGconn.Enable='on';
                app.Motorconn.Enable='on';
                app.Forward.Enable='on';
                app.MotorselectorSwitch.Enable='on';
                app.Autocentre.Enable='on';
                app.StopC.Enable='on';
                app.SensorcheckButton.Enable='on';
                app.Closecheck.Enable='on';
                app.Userdialog.Value = 'Sweep terminated!';
        end

        % Button pushed function: Forward
        function ForwardButtonPushed(app, event)
            app.Forward.Enable='off';
            app.Backwards.Enable='off';
            steps = abs(app.Piezo_steps.Value);
            speed = abs(app.Speed.Value);
            fprintf(app.piezoDriver, ['H' num2str(speed) 'J' num2str(-steps)]);
            pause(0.2)
            app.Forward.Enable='on';
            app.Backwards.Enable='on';
        end

        % Button pushed function: Stop_motor
        function Stop_motorButtonPushed(app, event)
            fprintf(app.piezoDriver,'S');
        end

        % Button pushed function: Backwards
        function BackwardsButtonPushed(app, event)
            app.Forward.Enable='off';
            app.Backwards.Enable='off';
            steps = abs(app.Piezo_steps.Value);
            speed = abs(app.Speed.Value);
            fprintf(app.piezoDriver, ['H' num2str(speed) 'J' num2str(steps)]);
            pause(0.2)
            app.Forward.Enable='on';
            app.Backwards.Enable='on';
        end

        % Value changed function: MotorselectorSwitch
        function MotorselectorSwitchValueChanged(app, event)
            value = app.MotorselectorSwitch.Value;
            if strcmp(value,'Load')
                app.MotorselectorSwitch.Enable = 'off';
                %fopen(app.piezoDriver);
                pause(0.1)
                fprintf(app.piezoDriver,'X2');
                pause(0.1)
                %fclose(app.piezoDriver);
                app.Userdialog.Value = 'Load motor selected';
                pause(0.1)
            elseif strcmp(value,'Short')
                app.MotorselectorSwitch.Enable = 'off';
                %fopen(app.piezoDriver);
                pause(0.1)
                fprintf(app.piezoDriver,'X5');
                pause(0.1)
                %fclose(app.piezoDriver);
                app.Userdialog.Value = 'Shortening motor selected';
                pause(0.1)
            end
            app.MotorselectorSwitch.Enable = 'on';
        end

        % Button pushed function: Autocentre
        function AutocentreValueChanged(app, event)
            import LibTiePie.Const.*;
            import LibTiePie.Enum.*;
            if app.scp.IsRunning == 1
                app.scp.stop();
            end
            app.StopC.Enable = 'on';
            app.Forward.Enable = 'off';
            app.Backwards.Enable = 'off';
            app.MotorselectorSwitch.Enable = 'off';
            app.Lamp_centre.Enable = 'on';
            % Insert positioning loop here!
            dts = [500e3 1/((5+1/4.8)*1e5) 1/1.953125e5];
            app.scp(1).MeasureMode = MM.STREAM;
            app.scp(1).Resolution = 16;
            dt_min = dts(3);
            dt_req = 1/(100*500);
            mult = round(dt_req/dt_min);
            fs = 1/(mult*dt_min);
            disp(num2str(fs))
            F_osc = fs/500;
            app.scp.SampleFrequency = fs;
            app.scp.RecordLength = 1000;
            fopen(app.fgen);
            fprintf(app.fgen, 'OUTP:SYNC ON');
            fprintf(app.fgen, 'OUTP:LOAD 50');
            fprintf(app.fgen, ['APPL:SIN ' num2str(F_osc) ', 0.5']);
            fprintf(app.fgen, 'VOLT:OFFS 0.25');
            fprintf(app.fgen, 'OUTP ON');
            fclose(app.fgen);
            pause(0.1)
            for ch = 1:2
                app.scp.Channels(ch).Enabled = true; %Set whether the channel records
                app.scp.Channels(ch).Range = 20;
                app.scp.Channels(ch).Coupling = CK.DCV;
            end
            for ch = 3:4
                app.scp.Channels(ch).Enabled = false; %Set whether the channel records
            end
            app.Userdialog.Value = 'Starting autopositioning routine!';
            tol = app.Tolerance.Value;
            meanF = inf;
            meanS = inf;
            tic
            h=figure(1);
            ylim([-12 12])
            hold on
            while abs(meanS) > tol || abs(meanF) > tol
                speed = app.Speed.Value;
                pause(1e-3)
                app.scp.start();
                while ~app.scp.IsDataReady
                    pause(1e-3)
                end
                app.scp.stop();
                pause(1e-3)
                dat = app.scp.getData();
                meanF = mean(dat(:,1));
                stepsF = round(app.GainF.Value*(meanF));
                app.Piezo_steps.Value = double(stepsF);
                meanS = mean(dat(:,2));
                stepsS = round(app.GainS.Value*(meanS));
                app.Piezo_steps.Value = double(stepsS);
                scatter(toc,meanF,'^','k','filled')
                scatter(toc,meanS,'o','k','filled')
                if toc<30
                    xlim([0 30])
                else
                    xlim([toc-30 toc])
                end
                %fopen(app.piezoDriver);
                if meanF < -8
                    stepsF = -10000;
                end
                app.Lamp_motor.Enable = 'on';
                fprintf(app.piezoDriver,'X2'); % selects the force CH1
                pause(0.1);
                fprintf(app.piezoDriver, ['H' num2str(speed) 'J' num2str(stepsF)]);
                pause(0.1);
                if meanS < -8
                    stepsS = -10000;
                end
                fprintf(app.piezoDriver,'X5'); % selects the shortening CH2
                pause(0.1);
                fprintf(app.piezoDriver, ['H' num2str(speed) 'J' num2str(stepsS)]);
                pause(0.1);
                %fclose(piezoDriver);
                if app.StopC.Value == 1
                    break
                end
            end
            fprintf(app.piezoDriver,'X2');
            close(h)
            app.StopC.Value = 0;
            app.StopC.Enable = 'off';
            app.Forward.Enable = 'on';
            app.Backwards.Enable = 'on';
            app.MotorselectorSwitch.Enable = 'on';
            app.Lamp_centre.Enable = 'off';
        end

        % Button pushed function: Motorconn
        function MotorconnButtonPushed(app, event)
            
            [INDX,~] = listdlg('ListString',app.devices,'SelectionMode','single','Name','Select COM port','OKString','Select');
            % Find a serial port object.
            if isempty(INDX)
                app.Userdialog.Value = 'Not connected to piezolegs!';
            else
                COM = app.devices{INDX};
                steps = -10000;
                speed = 1000;
                app.piezoDriver = instrfind('Type', 'serial', 'Port', COM, 'Tag', '');
                set(app.piezoDriver, 'BaudRate', 57600);
                % Create the serial port object if it does not exist
                % otherwise use the object that was found.
                if isempty(app.piezoDriver)
                    app.piezoDriver = serial(COM);
                else
                    fclose(app.piezoDriver);
                    app.piezoDriver = app.piezoDriver(1);
                end
                %Connect to instrument object, obj1.
                fopen(app.piezoDriver);
                app.Lamp_motor.Enable = 'on';
                app.MotorselectorSwitch.Value = 'Load';
                fprintf(app.piezoDriver,'X2'); % selects the force CH1
                pause(1);
                fprintf(app.piezoDriver,['H' num2str(speed)]);
                pause(1);
                fprintf(app.piezoDriver,sprintf('%+d',steps));
                pause(1);
                fprintf(app.piezoDriver,sprintf('%+d',steps));
                pause(1);
                app.MotorselectorSwitch.Value = 'Short';
                fprintf(app.piezoDriver,'X5'); % selects the shortening CH2
                pause(1);
                fprintf(app.piezoDriver,sprintf('%+d',steps));
                pause(1);
                fprintf(app.piezoDriver,sprintf('%+d',-steps));
                pause(1);
                fprintf(app.piezoDriver,'X2'); % selects the shortening CH2
                app.MotorselectorSwitch.Value = 'Load';
                %fclose(app.piezoDriver);
                app.Userdialog.Value = 'Connected to piezo motor!';
                if strcmp(app.Lamp_HS4.Enable,'on') && strcmp(app.Lamp_FG.Enable,'on')
                    app.RunButton.Enable='on';
                    app.StartSweep.Enable='on';
                    app.Forward.Enable = 'on';
                    app.Backwards.Enable = 'on';
                    app.Stop_motor.Enable = 'on';
                    app.MotorselectorSwitch.Enable = 'on';
                    app.Autocentre.Enable = 'on';
                    app.StopC.Enable = 'on';
                end
            end
        end

        % Menu selected function: SetdirectoryMenu
        function SetdirectoryMenuSelected(app, event)
            if isprop(app.dir,'set')
            else
                app.dir.prog = pwd;
            end
            eval(['addpath ' app.dir.prog]);
            app.dir.parent=uigetdir(pwd,'Select save location');
            if app.dir.parent == 0
                app.Userdialog.Value = 'Directory not set!';
            else
                app.dir.set = 1;
                cd(app.dir.parent);
            end
        end

        % Menu selected function: EnableallMenu
        function EnableallMenuSelected(app, event)
            app.Forward.Enable = 'on';
            app.Backwards.Enable = 'on';
            app.MotorselectorSwitch.Enable = 'on';
            app.RunButton.Enable='on';
            app.StartSweep.Enable='on';
            app.Forward.Enable = 'on';
            app.Backwards.Enable = 'on';
            app.Stop_motor.Enable = 'on';
            app.MotorselectorSwitch.Enable = 'on';
            app.Autocentre.Enable = 'on';
            app.StopC.Enable = 'on';
        end

        % Button pushed function: SensorcheckButton
        function SensorcheckButtonPushed(app, event)
            import LibTiePie.Const.*;
            import LibTiePie.Enum.*;
            if app.scp.IsRunning == 1
                app.scp.stop();
            end
            dts = [500e3 1/((5+1/4.8)*1e5) 1/1.953125e5];
            app.scp(1).MeasureMode = MM.STREAM;
            app.scp(1).Resolution = 16;
            dt_min = dts(3);
            dt_req = 1e-4;
            mult = round(dt_req/dt_min);
            fs = 1/(mult*dt_min);
            app.scp.SampleFrequency = fs;
            navg = 10000;
            app.scp.RecordLength = navg;
            for ch = 1:2
                app.scp.Channels(ch).Enabled = true; %Set whether the channel records
                app.scp.Channels(ch).Range = 20;
                app.scp.Channels(ch).Coupling = CK.DCV;
            end
            for ch = 3:4
                app.scp.Channels(ch).Enabled = false; %Set whether the channel records
            end
            h = figure(1);
            xlabel('Time [s]');
            ylabel('Amplitude [V]');
            rlength = 100;
            tic
            app.scp.start()
            pause(1e-4)
            while app.Closecheck.Value == 0 && ishandle(h)
                pause(1e-4)
                while ~app.scp.IsDataReady
                    pause(1e-4)
                end
                dat = app.scp.getData();
                t = toc;
                scatter(h,t,mean(dat(:,1)),'k')
                hold on
                scatter(h,t,mean(dat(:,2)),'r')
                
                if t < rlength
                    xlim([0 rlength])
                else
                    xlim([t-rlength t])
                end
            end
            app.scp.stop();
            close(h)
            app.Closecheck.Value = 0;
        end

        % Value changed function: FGon
        function FGonValueChanged(app, event)
            %Turns the output of the function generator on or off
            value = app.FGon.Value; %Get the current state of the function generator
            if value == 1
                fopen(app.fgen);
                fprintf(app.fgen, 'OUTP ON');
                fclose(app.fgen);
                app.FGon.Text = 'FG off';
                app.FGonLamp.Enable = 'on';
            else
                fopen(app.fgen);
                fprintf(app.fgen, 'OUTP OFF');
                fclose(app.fgen);
                app.FGon.Text = 'FG on';
                app.FGonLamp.Enable = 'off';
            end
        end

        % Menu selected function: SampleinformationMenu
        function SampleinformationMenuSelected(app, event)
            prompt = {'Sample name:','Sample length [mm]:','Inner diameter [mm]:','Outer diameter [mm]:','Notes:'};
            dims = [1 1 1 1 1];
            definput = {'S....','90','30','50','I like rocks'};
            resp = inputdlg(prompt,'Enter sample information',dims,definput);
            if isempty(resp)
                app.sampleinfo.set = 0;
            else
                app.sampleinfo.set = 1;
                app.sampleinfo.SampleName = resp{1};
                app.sampleinfo.l_mm = str2num(resp{2});
                app.sampleinfo.l_m = str2num(resp{2})/1000;
                app.sampleinfo.ID_mm = str2num(resp{3});
                app.sampleinfo.ID_m = str2num(resp{3})/1000;
                app.sampleinfo.OD_mm = str2num(resp{4});
                app.sampleinfo.OD_m = str2num(resp{4})/1000;
                app.sampleinfo.Notes = resp{5};
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SampleinformationMenu = uimenu(app.FileMenu);
            app.SampleinformationMenu.MenuSelectedFcn = createCallbackFcn(app, @SampleinformationMenuSelected, true);
            app.SampleinformationMenu.Text = 'Sample information...';
            app.SetdirectoryMenu = uimenu(app.FileMenu);
            app.SetdirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @SetdirectoryMenuSelected, true);
            app.SetdirectoryMenu.Text = 'Set directory';
            app.ConfigurationfileMenu = uimenu(app.FileMenu);
            app.ConfigurationfileMenu.Text = 'Configuration file';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.Text = 'Close';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.SaveQplotMenu = uimenu(app.ExportMenu);
            app.SaveQplotMenu.Text = 'Save Q plot';
            app.SaveOscillationplotMenu = uimenu(app.ExportMenu);
            app.SaveOscillationplotMenu.Text = 'Save Oscillation plot';
            app.SaveHysteresisplotMenu = uimenu(app.ExportMenu);
            app.SaveHysteresisplotMenu.Text = 'Save Hysteresis plot';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.EnableallMenu = uimenu(app.ViewMenu);
            app.EnableallMenu.MenuSelectedFcn = createCallbackFcn(app, @EnableallMenuSelected, true);
            app.EnableallMenu.Text = 'Enable all';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Amp [V]')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'Frequency [Hz]')
            ylabel(app.UIAxes2, 'Q^{-1} Value')
            app.UserdialogTextAreaLabel = uilabel(app.UIFigure);
            app.UserdialogTextAreaLabel.Text = 'User dialog';
            app.Userdialog = uitextarea(app.UIFigure);
            app.SingletestPanel = uipanel(app.UIFigure);
            app.SingletestPanel.Title = 'Single test';
            app.RunButton = uibutton(app.SingletestPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.F_oscEditFieldLabel = uilabel(app.SingletestPanel);
            app.F_oscEditFieldLabel.Text = 'F_osc';
            app.F_osc_sng = uieditfield(app.SingletestPanel, 'numeric');
            app.F_osc_sng.Limits = [0.1 190];
            app.F_osc_sng.Value = 10;
            app.VppEditFieldLabel = uilabel(app.SingletestPanel);
            app.VppEditFieldLabel.Text = 'Vpp';
            app.Vpp_sng = uieditfield(app.SingletestPanel, 'numeric');
            app.Vpp_sng.Limits = [0.1 4];
            app.Vpp_sng.Value = 1;
            app.NcycEditFieldLabel = uilabel(app.SingletestPanel);
            app.NcycEditFieldLabel.Text = 'Ncyc';
            app.Ncyc_sng = uieditfield(app.SingletestPanel, 'numeric');
            app.Ncyc_sng.Limits = [1 120];
            app.Ncyc_sng.Value = 10;
            app.OSEditFieldLabel = uilabel(app.SingletestPanel);
            app.OSEditFieldLabel.Text = 'OS';
            app.OS_sng = uieditfield(app.SingletestPanel, 'numeric');
            app.OS_sng.Limits = [100 1000];
            app.OS_sng.Value = 500;
            app.Lamp_single = uilamp(app.SingletestPanel);
            app.Lamp_single.Enable = 'off';
            app.SweepcontrolPanel = uipanel(app.UIFigure);
            app.SweepcontrolPanel.Title = 'Sweep control';
            app.Lamp_sweep = uilamp(app.SweepcontrolPanel);
            app.Lamp_sweep.Enable = 'off';
            app.StartSweep = uibutton(app.SweepcontrolPanel, 'push');
            app.StartSweep.ButtonPushedFcn = createCallbackFcn(app, @StartSweepButtonPushed, true);
            app.StartSweep.Enable = 'off';
            app.StartSweep.Text = 'Start Sweep';
            app.F_minEditField_2Label = uilabel(app.SweepcontrolPanel);
            app.F_minEditField_2Label.Text = 'F_min';
            app.F_min = uieditfield(app.SweepcontrolPanel, 'numeric');
            app.F_min.Limits = [0.01 200];
            app.F_min.Value = 0.1;
            app.F_maxEditFieldLabel = uilabel(app.SweepcontrolPanel);
            app.F_maxEditFieldLabel.Text = 'F_max';
            app.F_max = uieditfield(app.SweepcontrolPanel, 'numeric');
            app.F_max.Limits = [0.1 190];
            app.F_max.Value = 100;
            app.StepsEditFieldLabel = uilabel(app.SweepcontrolPanel);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.Steps = uieditfield(app.SweepcontrolPanel, 'numeric');
            app.Steps.Limits = [2 90];
            app.Steps.Value = 10;
            app.CyclesEditFieldLabel = uilabel(app.SweepcontrolPanel);
            app.CyclesEditFieldLabel.Text = 'Cycles';
            app.Cycles = uieditfield(app.SweepcontrolPanel, 'numeric');
            app.Cycles.Limits = [10 190];
            app.Cycles.Value = 100;
            app.OSEditFieldLabel_2 = uilabel(app.SweepcontrolPanel);
            app.OSEditFieldLabel_2.Text = 'OS';
            app.OS = uieditfield(app.SweepcontrolPanel, 'numeric');
            app.OS.Limits = [250 1000];
            app.OS.Value = 500;
            app.RepLabel = uilabel(app.SweepcontrolPanel);
            app.RepLabel.Text = 'Rep.';
            app.Repeats = uieditfield(app.SweepcontrolPanel, 'numeric');
            app.Repeats.Value = 3;
            app.Stopsweep = uibutton(app.SweepcontrolPanel, 'state');
            app.Stopsweep.Text = 'Stop sweep';
            app.VppEditFieldLabel_2 = uilabel(app.SweepcontrolPanel);
            app.VppEditFieldLabel_2.Text = 'Vpp';
            app.Vpp = uieditfield(app.SweepcontrolPanel, 'numeric');
            app.Vpp.Value = 0.5;
            app.Centremotor = uicheckbox(app.SweepcontrolPanel);
            app.Centremotor.Text = 'Centre motor';
            app.HardwarecommsPanel = uipanel(app.UIFigure);
            app.HardwarecommsPanel.Title = 'Hardware comms.';
            app.HS4conn = uibutton(app.HardwarecommsPanel, 'push');
            app.HS4conn.ButtonPushedFcn = createCallbackFcn(app, @HS4connPushed, true);
            app.HS4conn.Text = 'HS4 conn.';
            app.FGconn = uibutton(app.HardwarecommsPanel, 'push');
            app.FGconn.ButtonPushedFcn = createCallbackFcn(app, @FGconnPushed, true);
            app.FGconn.Text = 'FG conn.';
            app.Lamp_HS4 = uilamp(app.HardwarecommsPanel);
            app.Lamp_HS4.Enable = 'off';
            app.Lamp_FG = uilamp(app.HardwarecommsPanel);
            app.Lamp_FG.Enable = 'off';
            app.Motorconn = uibutton(app.HardwarecommsPanel, 'push');
            app.Motorconn.ButtonPushedFcn = createCallbackFcn(app, @MotorconnButtonPushed, true);
            app.Motorconn.Text = 'Motor conn.';
            app.Lamp_motor = uilamp(app.HardwarecommsPanel);
            app.Lamp_motor.Enable = 'off';
            app.FGon = uibutton(app.HardwarecommsPanel, 'state');
            app.FGon.ValueChangedFcn = createCallbackFcn(app, @FGonValueChanged, true);
            app.FGon.Text = 'FG on';
            app.FGonLamp = uilamp(app.HardwarecommsPanel);
            app.FGonLamp.Enable = 'off';
            app.SweepprogreesLabel = uilabel(app.UIFigure);
            app.SweepprogreesLabel.Text = 'Sweep progrees';
            app.progressbar = uigauge(app.UIFigure, 'linear');
            app.progressbar.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.progressbar.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', ''};
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, 'Axial strain [n\epsilon]')
            ylabel(app.UIAxes3, 'Axial load [N]')
            app.PiezomotorcontrolPanel = uipanel(app.UIFigure);
            app.PiezomotorcontrolPanel.Title = 'Piezo motor control';
            app.Forward = uibutton(app.PiezomotorcontrolPanel, 'push');
            app.Forward.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.Forward.Text = '>>>';
            app.Stop_motor = uibutton(app.PiezomotorcontrolPanel, 'push');
            app.Stop_motor.ButtonPushedFcn = createCallbackFcn(app, @Stop_motorButtonPushed, true);
            app.Stop_motor.Text = 'Stop';
            app.Backwards = uibutton(app.PiezomotorcontrolPanel, 'push');
            app.Backwards.ButtonPushedFcn = createCallbackFcn(app, @BackwardsButtonPushed, true);
            app.Backwards.Text = '<<<';
            app.MotorselectorSwitch = uiswitch(app.PiezomotorcontrolPanel, 'toggle');
            app.MotorselectorSwitch.Items = {'Load', 'Short'};
            app.MotorselectorSwitch.ValueChangedFcn = createCallbackFcn(app, @MotorselectorSwitchValueChanged, true);
            app.MotorselectorSwitch.Value = 'Load';
            app.StepsEditFieldLabel_2 = uilabel(app.PiezomotorcontrolPanel);
            app.StepsEditFieldLabel_2.Text = 'Steps';
            app.Piezo_steps = uieditfield(app.PiezomotorcontrolPanel, 'numeric');
            app.Piezo_steps.Limits = [-100000 100000];
            app.Piezo_steps.Value = 1;
            app.Lamp_centre = uilamp(app.PiezomotorcontrolPanel);
            app.Lamp_centre.Enable = 'off';
            app.ToleranceVEditFieldLabel = uilabel(app.PiezomotorcontrolPanel);
            app.ToleranceVEditFieldLabel.Text = 'Tolerance (V)';
            app.Tolerance = uieditfield(app.PiezomotorcontrolPanel, 'numeric');
            app.Tolerance.Value = 1;
            app.GainFLabel = uilabel(app.PiezomotorcontrolPanel);
            app.GainFLabel.Text = 'Gain F';
            app.GainF = uieditfield(app.PiezomotorcontrolPanel, 'numeric');
            app.GainF.Value = 1000;
            app.GainSEditFieldLabel = uilabel(app.PiezomotorcontrolPanel);
            app.GainSEditFieldLabel.Text = 'Gain S';
            app.GainS = uieditfield(app.PiezomotorcontrolPanel, 'numeric');
            app.GainS.Value = 1000;
            app.SpeedEditFieldLabel = uilabel(app.PiezomotorcontrolPanel);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.Speed = uieditfield(app.PiezomotorcontrolPanel, 'numeric');
            app.Speed.Limits = [-10000 10000];
            app.Speed.Value = 100;
            app.Autocentre = uibutton(app.PiezomotorcontrolPanel, 'push');
            app.Autocentre.ButtonPushedFcn = createCallbackFcn(app, @AutocentreValueChanged, true);
            app.Autocentre.Text = 'Autocentre';
            app.StopC = uibutton(app.PiezomotorcontrolPanel, 'state');
            app.StopC.Text = 'Stop centering';
            app.CurrentdirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentdirectoryEditFieldLabel.Text = 'Current directory';
            app.Currentdirectory = uieditfield(app.UIFigure, 'text');
            app.Currentdirectory.Value = '<dir>';
            app.SensorcheckButton = uibutton(app.UIFigure, 'push');
            app.SensorcheckButton.ButtonPushedFcn = createCallbackFcn(app, @SensorcheckButtonPushed, true);
            app.SensorcheckButton.Text = 'Sensor check';
            app.Closecheck = uibutton(app.UIFigure, 'state');
            app.Closecheck.Text = 'Close check';
            app.CycleprogressGaugeLabel = uilabel(app.UIFigure);
            app.CycleprogressGaugeLabel.Text = 'Cycle progress';
            app.Cycleprogress = uigauge(app.UIFigure, 'linear');
            app.Cycleprogress.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Cycleprogress.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', ''};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Q_daq2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end